classdef apParcial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnsealeaAlakazamnuevamusicaLabel_4  matlab.ui.control.Label
        EnsealeaAlakazamnuevamusicaLabel_3  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        AlakazameaotracancionButton_2   matlab.ui.control.Button
        EnsealeaAlakazamnuevamusicaLabel_2  matlab.ui.control.Label
        DescubrequecancionsubisteLabel  matlab.ui.control.Label
        SubelacancionButton             matlab.ui.control.Button
        ShazameaEditField_2             matlab.ui.control.EditField
        PontucancionButton_2            matlab.ui.control.Button
        OLabel                          matlab.ui.control.Label
        EnsealeaAlakazamnuevamusicaLabel  matlab.ui.control.Label
        AlakazameaotracancionButton     matlab.ui.control.Button
        FrgamentoenelsegundoLabel       matlab.ui.control.Label
        TucancionesLabel                matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AlakazameaButton                matlab.ui.control.Button
        ShazameaEditField               matlab.ui.control.EditField
        PontucancionButton              matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cancionInput

        dbDirectory
        cancionesDB

        desfaseTiempo
        cancionMatch

    end
    
    methods (Access = private)
        function setDatabase(app) %TEMPORAL
            app.dbDirectory = 'superBaseDeDatosSuperSeguraConSuperDatos';
            dbInfo = dir(app.dbDirectory);
            sz = size(dbInfo,1);
            numFiles = 0;

            for i = 1: 1: sz
                if (contains(lower(dbInfo(i).name),".mp3") || contains(lower(dbInfo(i).name),".wav"))
                   numFiles = numFiles + 1;
                end
            end 

            for k = 1:numFiles
               objArray(k) = cancion;
            end
            numFiles = 1;
            
            for i = 1: 1: sz
                if (contains(lower(dbInfo(i).name),".mp3") || contains(lower(dbInfo(i).name),".wav"))
                   objArray(numFiles).setSong(app.dbDirectory + "/" +  dbInfo(i).name);
                   numFiles = numFiles + 1;
                end
            end 

            app.cancionesDB = objArray;
        end
   

        function plotInputSong(app)
            t = 0:seconds(1/app.cancionInput.sampleFrec):seconds(app.cancionInput.duration);
            t = t(1:end-1);
            title(app.UIAxes, app.cancionInput.fileName);
            plot(app.UIAxes, t,app.cancionInput.signal);       
        end

        function plotSongMatch(app)
            t = 0:seconds(1/app.cancionMatch.sampleFrec):seconds(app.cancionMatch.duration);
            t = t(1:end-1);

            size(app.cancionMatch.signal)
            size(t)

            hold(app.UIAxes2,'on');
            title(app.UIAxes2, app.cancionMatch.fileName);
            plot(app.UIAxes2, t,app.cancionMatch.signal);  
            xregion(app.UIAxes2, app.desfaseTiempo , app.cancionInput.duration + app.desfaseTiempo, 'FaceColor', 'g'); 
            hold(app.UIAxes2,'off');
            
        end

        function [cor, lag] = getCor(app, cancionComp)
            [cor, lag] = xcorr(cancionComp.signal ,app.cancionInput.signal);
            return 
        end

            
        function timeDiff = getTimeDiff(app, cor, lag)
            [~,I] = max(abs(cor));
            SampleDiff = lag(I);
            timeDiff = SampleDiff/48000;
            return
        end

        function find = lookForMatch(app)
            correlationF = 0;
            lagF = 0;
            indxF = 0;
            find = false;

            for i = 1 : size(app.cancionesDB,2)
                [cor, lag] = getCor(app, app.cancionesDB(i));
                if (max(abs(cor)) > max(abs(correlationF)))
                    correlationF = cor;
                    lagF = lag;
                    indxF = i;
                end 
            end
    
            if (max(abs(correlationF)) > 5.1486e+03)
                indxF
                size(app.cancionesDB)

                app.desfaseTiempo = getTimeDiff(app, correlationF, lagF)
                app.cancionMatch = app.cancionesDB(indxF);
                app.cancionMatch.fileName
                find = true;
            else
                find = false;
            end
            return
        end


        function resetapp(app)
            
            app.ShazameaEditField.Value = "";
            app.ShazameaEditField_2.Value = "";
            app.cancionInput = cancion;

            app.dbDirectory = "";
            app.cancionesDB = [];
    
            app.desfaseTiempo = 0;
            app.cancionMatch = "";


            app.TucancionesLabel.Visible = "off";
            app.FrgamentoenelsegundoLabel.Visible = "off";

            app.UIAxes.Visible = "off";
            cla(app.UIAxes) 
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2)
            

            app.TucancionesLabel.Text = "Tu cancion es:";
            app.FrgamentoenelsegundoLabel.Text = "Frgamento en el segundo:";
            app.AlakazameaotracancionButton.Visible = "off";

            app.EnsealeaAlakazamnuevamusicaLabel_2.Visible = "off"
            app.AlakazameaotracancionButton_2.Visible = "off"

            app.OLabel.Visible = "on";
            app.ShazameaEditField_2.Visible = "on";
            app.EnsealeaAlakazamnuevamusicaLabel.Visible = "on";
            app.DescubrequecancionsubisteLabel.Visible = "on";
            app.ShazameaEditField.Visible = "on";
            app.PontucancionButton.Visible = "on";
            app.PontucancionButton_2.Visible = "on";
            app.EnsealeaAlakazamnuevamusicaLabel_4.Visible = "off"
            app.EnsealeaAlakazamnuevamusicaLabel_3.Visible = "off"
            clear all
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PontucancionButton
        function PontucancionButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3';'*.wav'});
            if (filename ~= 0)
                app.ShazameaEditField.Value = filename;
                app.cancionInput = cancion;
                app.cancionInput.setSong(filename);
                figure(app.UIFigure);
                app.AlakazameaButton.Visible = "on";
                app.OLabel.Visible = "off";
                app.ShazameaEditField_2.Visible = "off";
                app.EnsealeaAlakazamnuevamusicaLabel.Visible = "off";
                app.PontucancionButton_2.Visible = "off";
            end 
        end

        % Value changed function: ShazameaEditField
        function ShazameaEditFieldValueChanged(app, event)
            value = app.ShazameaEditField.Value;
            
        end

        % Button pushed function: AlakazameaButton
        function AlakazameaButtonPushed(app, event)
            app.Image2.Visible = "on";
            app.AlakazameaButton.Visible = "off";  
            app.setDatabase(); 
            app.plotInputSong();
            app.UIAxes.Visible = "on";


            find = app.lookForMatch();
            app.Image2.Visible = "off"
            if (find == true)
                app.plotSongMatch();
                app.UIAxes2.Visible = "on";
    
                app.TucancionesLabel.Text = app.TucancionesLabel.Text + app.cancionMatch.fileName;
                app.FrgamentoenelsegundoLabel.Text = app.FrgamentoenelsegundoLabel.Text + string(app.desfaseTiempo);
                app.TucancionesLabel.Visible = "on";
                app.FrgamentoenelsegundoLabel.Visible = "on";
                %sound(app.cancionMatch.signal(app.desfaseTiempo:app.cancionMatch.duration),48000)
                
            else
                
                app.EnsealeaAlakazamnuevamusicaLabel_4.Visible = "on"
                app.EnsealeaAlakazamnuevamusicaLabel_3.Visible = "on"
            end
            app.AlakazameaotracancionButton.Visible = "on";
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: AlakazameaotracancionButton
        function AlakazameaotracancionButtonPushed(app, event)
            app.resetapp();
        end

        % Value changed function: ShazameaEditField_2
        function ShazameaEditField_2ValueChanged(app, event)
            value = app.ShazameaEditField_2.Value;
            
        end

        % Button pushed function: PontucancionButton_2
        function PontucancionButton_2Pushed(app, event)
            [filename, path] = uigetfile({'*.mp3';'*.wav'});
            if (filename ~= 0)
                app.ShazameaEditField_2.Value = filename;
                app.cancionInput = cancion;
                app.cancionInput.setSong(filename);
                figure(app.UIFigure);
                app.SubelacancionButton.Visible = "on";

                app.OLabel.Visible = "off";
                app.ShazameaEditField.Visible = "off";
                app.DescubrequecancionsubisteLabel.Visible = "off";
                app.PontucancionButton.Visible = "off";
            end 
        end

        % Button pushed function: SubelacancionButton
        function SubelacancionButtonPushed(app, event)
            app.dbDirectory = 'superBaseDeDatosSuperSeguraConSuperDatos/';

            newFormat = strrep(string(app.cancionInput.fileName),'mp3','wav');
            
            audiowrite(string(app.dbDirectory) + string(newFormat) , app.cancionInput.signal , 48000);
            app.SubelacancionButton.Visible = "off";
            app.ShazameaEditField_2.Visible = "off";
            app.EnsealeaAlakazamnuevamusicaLabel.Visible = "off";
            app.PontucancionButton_2.Visible = "off";
            app.EnsealeaAlakazamnuevamusicaLabel_2.Visible = "on"
            app.AlakazameaotracancionButton_2.Visible = "on"
        end

        % Button pushed function: AlakazameaotracancionButton_2
        function AlakazameaotracancionButton_2Pushed(app, event)
            app.resetapp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Señal de audio')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Señal de audio')
            app.UIAxes2.Visible = 'off';
            app.PontucancionButton = uibutton(app.UIFigure, 'push');
            app.PontucancionButton.ButtonPushedFcn = createCallbackFcn(app, @PontucancionButtonPushed, true);
            app.PontucancionButton.Text = 'Pon tu cancion!';
            app.ShazameaEditField = uieditfield(app.UIFigure, 'text');
            app.ShazameaEditField.ValueChangedFcn = createCallbackFcn(app, @ShazameaEditFieldValueChanged, true);
            app.AlakazameaButton = uibutton(app.UIFigure, 'push');
            app.AlakazameaButton.ButtonPushedFcn = createCallbackFcn(app, @AlakazameaButtonPushed, true);
            app.AlakazameaButton.Visible = 'off';
            app.AlakazameaButton.Text = 'Alakazamea';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo.png');
            app.TucancionesLabel = uilabel(app.UIFigure);
            app.TucancionesLabel.Visible = 'off';
            app.TucancionesLabel.Text = 'Tu cancion es:';
            app.FrgamentoenelsegundoLabel = uilabel(app.UIFigure);
            app.FrgamentoenelsegundoLabel.Visible = 'off';
            app.FrgamentoenelsegundoLabel.Text = 'Frgamento en el segundo:';
            app.AlakazameaotracancionButton = uibutton(app.UIFigure, 'push');
            app.AlakazameaotracancionButton.ButtonPushedFcn = createCallbackFcn(app, @AlakazameaotracancionButtonPushed, true);
            app.AlakazameaotracancionButton.Visible = 'off';
            app.AlakazameaotracancionButton.Text = {'Alakazamea '; 'otra cancion'};
            app.EnsealeaAlakazamnuevamusicaLabel = uilabel(app.UIFigure);
            app.EnsealeaAlakazamnuevamusicaLabel.Text = 'Enseñale a Alakazam nueva musica!!!';
            app.OLabel = uilabel(app.UIFigure);
            app.OLabel.Text = 'O';
            app.PontucancionButton_2 = uibutton(app.UIFigure, 'push');
            app.PontucancionButton_2.ButtonPushedFcn = createCallbackFcn(app, @PontucancionButton_2Pushed, true);
            app.PontucancionButton_2.Text = 'Pon tu cancion!';
            app.ShazameaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ShazameaEditField_2.ValueChangedFcn = createCallbackFcn(app, @ShazameaEditField_2ValueChanged, true);
            app.SubelacancionButton = uibutton(app.UIFigure, 'push');
            app.SubelacancionButton.ButtonPushedFcn = createCallbackFcn(app, @SubelacancionButtonPushed, true);
            app.SubelacancionButton.Visible = 'off';
            app.SubelacancionButton.Text = 'Sube la cancion!';
            app.DescubrequecancionsubisteLabel = uilabel(app.UIFigure);
            app.DescubrequecancionsubisteLabel.Text = 'Descubre que cancion subiste';
            app.EnsealeaAlakazamnuevamusicaLabel_2 = uilabel(app.UIFigure);
            app.EnsealeaAlakazamnuevamusicaLabel_2.Visible = 'off';
            app.EnsealeaAlakazamnuevamusicaLabel_2.Text = 'Se subio la cancion exitosamente! Gracias por enseñarle a Alakazam nueva musica ';
            app.AlakazameaotracancionButton_2 = uibutton(app.UIFigure, 'push');
            app.AlakazameaotracancionButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlakazameaotracancionButton_2Pushed, true);
            app.AlakazameaotracancionButton_2.Visible = 'off';
            app.AlakazameaotracancionButton_2.Text = {'Alakazamea '; 'otra cancion'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'hourglass.gif');
            app.EnsealeaAlakazamnuevamusicaLabel_3 = uilabel(app.UIFigure);
            app.EnsealeaAlakazamnuevamusicaLabel_3.Visible = 'off';
            app.EnsealeaAlakazamnuevamusicaLabel_3.Text = 'No conocemos tu cancion :C, pero nos la puedes enseñar! :D ';
            app.EnsealeaAlakazamnuevamusicaLabel_4 = uilabel(app.UIFigure);
            app.EnsealeaAlakazamnuevamusicaLabel_4.Visible = 'off';
            app.EnsealeaAlakazamnuevamusicaLabel_4.Text = 'Haz click en el boton ->';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apParcial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end