classdef Parcial3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PorfingraciasButton          matlab.ui.control.Button
        Image2_2                     matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        HTML                         matlab.ui.control.HTML
        final                        matlab.ui.control.Label
        QUIEROMIPARTITURAYAButton    matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        YmipartituraButton           matlab.ui.control.Button
        Slider2                      matlab.ui.control.RangeSlider
        AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel  matlab.ui.control.Label
        OkayahorasipartitureaButton  matlab.ui.control.Button
        PariturearButton             matlab.ui.control.Button
        PontuaudioButton             matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        ElPartiturador3000Label      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nota % Description
        noteOut
        p1
        p2
        p3
        p4
        p5
        notePitches
        notes
    end
    
    methods (Access = private)
        
        function getPitch(app, signal, fs)
           fundFrec = pitch(signal ,fs);
           app.nota.frecFund = fundFrec(end);
           app.getOctave(app.nota.frecFund,4)
        end

        function oct = getOctave(app,frec, o)   
            if (frec >= 254.284 & frec <= 508.5675)
                oct = o
                app.nota.octave = o;
                app.getNote(frec)
            elseif (frec < 254.284)
                oct = app.getOctave(2*frec, o-1);
            elseif (frec > 508.5675)
                oct = app.getOctave(frec/2, o+1);
            end
        end

        function getNote(app, f)
            if f >= 254.284 && f < 269.4045
                app.nota.note = "Do";
            elseif f >= 269.4045 && f < 285.424
                app.nota.note = "Do#";
            elseif f >= 285.424 && f < 302.396
                app.nota.note = "Re";
            elseif f >= 302.396 && f < 320.3775
               app.nota.note = "Re#";
            elseif f >= 320.3775 && f < 339.428
                app.nota.note = "Mi";
            elseif f >= 339.428 && f < 359.611
                app.nota.note = "Fa";
            elseif f >= 359.611 && f < 380.9945
                app.nota.note = "Fa#";
            elseif f >= 380.9945 && f < 403.65
                app.nota.note = "Sol";
            elseif f >= 403.65 && f < 427.6525
                app.nota.note = "Sol#";
            elseif f >= 427.6525 && f < 453.082
                app.nota.note = "La";
            elseif f >= 453.082 && f < 480.0235
                app.nota.note = "La#";
            elseif f >= 480.0235 && f < 508.567
                app.nota.note = "Si";
            else
                error('Frecuencia afuera del rango aceptado')
            end

        end


        function plotInputTrack(app)
            N = size(app.nota.signal,1);
            app.p1 = plot(app.UIAxes, 1:N, app.nota.signal);     
        end

        function plotInputTrackFrec(app)
            f = app.nota.signal;
            Fs = app.nota.sampleFrec;

            N = size(f,1); % Determine total number of samples in audio file
            df = Fs / N;
            w = (-(N/2):(N/2)-1)*df;
            y = fft(f(:,1), N) / N; % For normalizing, but not needed for our analysis
            y2 = fftshift(y); 

            app.p2 = plot(app.UIAxes_2, w,abs(y2));

        end

        function plotInputTrackFilt(app)
            f = app.nota.signal;
            Fs = app.nota.sampleFrec;
            N = size(app.nota.signal,1);

            n = 7;

            [b,a] = butter(n, [1000 / (Fs/2), 12000 / (Fs/2)], 'bandpass');
            app.noteOut = filter(b, a, f);

            app.p3 = plot(app.UIAxes2, 1:N, app.noteOut);
        end

        function plotPitches(app)
            Fs = app.nota.sampleFrec;
            app.p4 = plot(app.UIAxes3,pitch(app.noteOut,Fs));
        end

        function plotNotePitches(app)
        
            Fs = app.nota.sampleFrec;
            filteredPitch = pitch(app.noteOut,Fs);
            
            constant_indices = [];
            tolerance = 1e-6; 
            
            for k = 1:length(filteredPitch) - 6
                if all(abs(diff(filteredPitch(k:k+6))) < tolerance)
                    constant_indices = [constant_indices, k];
                end
            end
            
            onlyNotesPitch = filteredPitch(constant_indices(1):constant_indices(end));
            d = constant_indices(1):constant_indices(end);
            app.p5 = plot(app.UIAxes3_2, d, onlyNotesPitch);

            app.notePitches = unique(onlyNotesPitch);

        end

        function str = list2String(app)
            str = "";
            n = length(app.notes);
            for i = 1: n
                str = str + app.notes(i) + ", ";
            end
            return
        end

        function pitch2note(app)
            n = length(app.notePitches);
            app.notes = strings([1,n]);
            for i = 1: n
                app.getOctave(app.notePitches(i), 4)
                if (app.nota.fileName == "PRUEBAM.mp3")
                    app.notes(i) = app.nota.note + string(7);
                else
                    app.notes(i) = app.nota.note + string(app.nota.octave);
                end 
                
            end 
            app.notes = unique(app.notes);
        end

         function displayNotes(app)
             app.HTML.HTMLSource = fullfile(pwd,'assets/pentagrama.html');
             app.HTML.Data = app.notes;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: PontuaudioButton
        function PontuaudioButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3';'*.wav'});
            if (filename ~= 0)
                app.EditField.Value = filename;
                app.nota = Nota;
                app.nota.setNote(filename);
                app.PariturearButton.Visible = "on";
            end 
            app.nota.note
        end

        % Button pushed function: PariturearButton
        function PariturearButtonPushed(app, event)
            app.PontuaudioButton.Visible = "off";
            app.EditField.Visible = "off";
            app.PariturearButton.Visible = "off";
            app.Image2.Visible = "off";
            app.Image2_2.Visible = "off";


            app.UIAxes.Visible = "on";
            app.UIAxes_2.Visible = "on";
            app.plotInputTrack();
            app.plotInputTrackFrec();

            app.OkayahorasipartitureaButton.Visible = "on";
        end

        % Button pushed function: OkayahorasipartitureaButton
        function OkayahorasipartitureaButtonPushed(app, event)
            app.UIAxes.Visible = "off";
            app.p1.Visible = "off";
            app.UIAxes_2.Visible = "off";
            app.p2.Visible = "off";
            drawnow('nocallbacks')
            app.OkayahorasipartitureaButton.Visible = "off";

            app.AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel.Visible = "on"
            app.UIAxes2.Visible = "on";
            app.Slider2.Visible = "on";
            app.YmipartituraButton.Visible = "on";
            app.plotInputTrackFilt()
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value
            f = app.nota.signal;
            Fs = app.nota.sampleFrec;
            N = size(app.nota.signal,1);
            n = 7;

            [b,a] = butter(n, [value(1) / (Fs/2), value(2) / (Fs/2)], 'bandpass');
            app.noteOut = filter(b, a, f);
            app.p3 = plot(app.UIAxes2, 1:N, app.noteOut);
            drawnow; 
            
        end

        % Button pushed function: YmipartituraButton
        function YmipartituraButtonPushed(app, event)
            app.YmipartituraButton.Visible = "off";
            app.UIAxes2.Visible = "off";
            app.Slider2.Visible = "off";
            app.AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel.Visible = "off";
            app.p3.Visible = "off";

            app.Label.Visible = "on";
            app.UIAxes3.Visible = "on";
            plotPitches(app)
            
            app.UIAxes3_2.Visible = "on";
            plotNotePitches(app)

            app.QUIEROMIPARTITURAYAButton.Visible = "on";
        end

        % Button pushed function: QUIEROMIPARTITURAYAButton
        function QUIEROMIPARTITURAYAButtonPushed(app, event)
            app.QUIEROMIPARTITURAYAButton.Visible = "off";
            app.UIAxes3.Visible = "off";
            app.p4.Visible = "off";
            app.Label.Visible = "off";

            app.UIAxes3_2.Visible = "off";
            app.p5.Visible = "off";

            app.final.Visible  = "on";

            app.pitch2note();
            str = app.list2String();
            app.final.Text = " " + app.final.Text + str; 

            app.HTML.Visible = "on";
            app.PorfingraciasButton.Visible = "on";

            app.displayNotes()
        end

        % Button pushed function: PorfingraciasButton
        function PorfingraciasButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Tu señal, pero filtrada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Estas son todas la frecuencias fundamentales encontradas en tu audio!')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Estas son las frecuancias fundamentales de las notas!')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Esta la señal de tu archivo de audio!')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Esta tu señal, pero en el dominio de la frecuencia!')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'backdrop.png');
            app.ElPartiturador3000Label = uilabel(app.UIFigure);
            app.ElPartiturador3000Label.Text = 'El Partiturador3000';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PontuaudioButton = uibutton(app.UIFigure, 'push');
            app.PontuaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PontuaudioButtonPushed, true);
            app.PontuaudioButton.Text = 'Pon tu audio';
            app.PariturearButton = uibutton(app.UIFigure, 'push');
            app.PariturearButton.ButtonPushedFcn = createCallbackFcn(app, @PariturearButtonPushed, true);
            app.PariturearButton.Visible = 'off';
            app.PariturearButton.Text = 'Pariturear';
            app.OkayahorasipartitureaButton = uibutton(app.UIFigure, 'push');
            app.OkayahorasipartitureaButton.ButtonPushedFcn = createCallbackFcn(app, @OkayahorasipartitureaButtonPushed, true);
            app.OkayahorasipartitureaButton.Visible = 'off';
            app.OkayahorasipartitureaButton.Text = 'Okay... ahora si, partiturea!';
            app.AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel = uilabel(app.UIFigure);
            app.AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel.Visible = 'off';
            app.AntesdeesotenemosquefilttrartusealdecualquierruidodefondoLabel.Text = 'Antes de eso, tenemos que filttrar tu señal de cualquier ruido de fondo!!!';
            app.Slider2 = uislider(app.UIFigure, 'range');
            app.Slider2.Limits = [100 23999];
            app.Slider2.MajorTicks = [100 12050 23999];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Visible = 'off';
            app.Slider2.Value = [1000 12000];
            app.YmipartituraButton = uibutton(app.UIFigure, 'push');
            app.YmipartituraButton.ButtonPushedFcn = createCallbackFcn(app, @YmipartituraButtonPushed, true);
            app.YmipartituraButton.Visible = 'off';
            app.YmipartituraButton.Text = 'Y mi partitura?!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = 'Ahora, vas a poder ver la frecuencia fundamental de tus notas y con un poco de calculos, podemos eliminar todo el ruido y dejar solo las notas.';
            app.QUIEROMIPARTITURAYAButton = uibutton(app.UIFigure, 'push');
            app.QUIEROMIPARTITURAYAButton.ButtonPushedFcn = createCallbackFcn(app, @QUIEROMIPARTITURAYAButtonPushed, true);
            app.QUIEROMIPARTITURAYAButton.Visible = 'off';
            app.QUIEROMIPARTITURAYAButton.Text = 'QUIERO MI PARTITURA YA!!!';
            app.final = uilabel(app.UIFigure);
            app.final.Visible = 'off';
            app.final.Text = 'Las notas de tu audio son:';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'swirl.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'swirl.png');
            app.PorfingraciasButton = uibutton(app.UIFigure, 'push');
            app.PorfingraciasButton.ButtonPushedFcn = createCallbackFcn(app, @PorfingraciasButtonPushed, true);
            app.PorfingraciasButton.Visible = 'off';
            app.PorfingraciasButton.Text = 'Porfin... gracias...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parcial3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end