classdef PresentePresidente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        noeresdeestaclaseperovienvenidxLabel  matlab.ui.control.Label
        HolaLabel                     matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        AadirestudianteButton         matlab.ui.control.Button
        vinieronaclaseLabel           matlab.ui.control.Label
        CerrarprogramaButton          matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        SeguirllamandoalistaButton    matlab.ui.control.Button
        TerminardellamaralistaButton  matlab.ui.control.Button
        Image2_5                      matlab.ui.control.Image
        Image2_4                      matlab.ui.control.Image
        Image2_3                      matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        LlamarlistaButton             matlab.ui.control.Button
        QuiennovinoaLovelacehoyLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        popUpLlamar
        popUpAnadir
        personas
    end
    
    methods (Access = public)
        
        function GetPersonInClass(app, num)
            app.SeguirllamandoalistaButton.Visible = "on";
            app.TerminardellamaralistaButton.Visible = "on";
            
            if (num == 1)
                app.manageJuanes()

            elseif (num == 2)
                app.manageJuli()


            elseif (num == 3)
                app.manageLia()


            elseif (num == 4)
                app.manageTato()


            % elseif (num == 5)
            %     app.manageNico()

            elseif (num >= 5)
                app.manageNo(num)
            end
        end
    end
    
    methods (Access = private)
        
        function manageTato(app)
            app.Image2.Visible = "on";
            app.personas = app.personas + "Tato, ";
            
        end

        function manageLia(app)
            app.Image2_2.Visible = "on";
            app.personas = app.personas + "Lia, ";
        end

        function manageJuanes(app)
            app.Image2_4.Visible = "on";
            app.personas = app.personas + "juanes, ";
        end

        function manageJuli(app)
            app.Image2_3.Visible = "on";
            app.personas = app.personas + "juli, ";
        end

        %Nico nunca mando audio :c 
        % function manageNico(app)
        %     app.Image2_5.Visible = "on";
        %     app.personas = app.personas + "Nico, ";
        % end

        function manageNo(app, num)
            DBfiles = dir("baseDeDatosReChidori\");
            file ="baseDeDatosReChidori\" + DBfiles(num+2).name;
            getname = split(file,"_");
            getname = erase(getname(2),".mp3");
            app.personas = app.personas + getname + ", ";

            app.Label_2.Visible = "on";
            app.HolaLabel.Visible = "on";
            app.noeresdeestaclaseperovienvenidxLabel.Visible = "on";

            app.HolaLabel.Text = app.HolaLabel.Text + getname;

            pause(5)

            app.HolaLabel.Text = erase(app.HolaLabel.Text, getname)

            app.Label_2.Visible = "off";
            app.HolaLabel.Visible = "off";
            app.noeresdeestaclaseperovienvenidxLabel.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LlamarlistaButton
        function LlamarlistaButtonPushed(app, event)
            app.LlamarlistaButton.Visible = "off";
            app.personas = "";
            app.popUpLlamar = llamadindividualPopUp(app);
        end

        % Button pushed function: SeguirllamandoalistaButton
        function SeguirllamandoalistaButtonPushed(app, event)
            app.SeguirllamandoalistaButton.Visible = "off";
            app.TerminardellamaralistaButton.Visible = "off";
            app.popUpLlamar = llamadindividualPopUp(app);
        end

        % Button pushed function: TerminardellamaralistaButton
        function TerminardellamaralistaButtonPushed(app, event)
            app.TerminardellamaralistaButton.Visible = "off";
            app.SeguirllamandoalistaButton.Visible = "off";
            app.Label.Visible = "on";
            app.CerrarprogramaButton.Visible = "on";
            app.vinieronaclaseLabel.Visible = "on";
            app.vinieronaclaseLabel.Text = app.personas + app.vinieronaclaseLabel.Text;
            app.AadirestudianteButton.Visible = "on";
        end

        % Button pushed function: CerrarprogramaButton
        function CerrarprogramaButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AadirestudianteButton
        function AadirestudianteButtonPushed(app, event)
            app.popUpAnadir = anadirEstudiantePopUp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'lovelace.png');
            app.QuiennovinoaLovelacehoyLabel = uilabel(app.UIFigure);
            app.QuiennovinoaLovelacehoyLabel.Text = '¿Quien no vino a Lovelace hoy?';
            app.LlamarlistaButton = uibutton(app.UIFigure, 'push');
            app.LlamarlistaButton.ButtonPushedFcn = createCallbackFcn(app, @LlamarlistaButtonPushed, true);
            app.LlamarlistaButton.Text = 'Llamar lista';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'tato.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.Visible = 'off';
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'lia.png');
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.Visible = 'off';
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'assets', 'juli.png');
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.Visible = 'off';
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'assets', 'juanes.png');
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.Visible = 'off';
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'assets', 'nico.png');
            app.TerminardellamaralistaButton = uibutton(app.UIFigure, 'push');
            app.TerminardellamaralistaButton.ButtonPushedFcn = createCallbackFcn(app, @TerminardellamaralistaButtonPushed, true);
            app.TerminardellamaralistaButton.Visible = 'off';
            app.TerminardellamaralistaButton.Text = 'Terminar de llamar a lista ';
            app.SeguirllamandoalistaButton = uibutton(app.UIFigure, 'push');
            app.SeguirllamandoalistaButton.ButtonPushedFcn = createCallbackFcn(app, @SeguirllamandoalistaButtonPushed, true);
            app.SeguirllamandoalistaButton.Visible = 'off';
            app.SeguirllamandoalistaButton.Text = 'Seguir llamando a lista';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = {'  '; ''; ''; ''};
            app.CerrarprogramaButton = uibutton(app.UIFigure, 'push');
            app.CerrarprogramaButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarprogramaButtonPushed, true);
            app.CerrarprogramaButton.Visible = 'off';
            app.CerrarprogramaButton.Text = 'Cerrar programa';
            app.vinieronaclaseLabel = uilabel(app.UIFigure);
            app.vinieronaclaseLabel.Visible = 'off';
            app.vinieronaclaseLabel.Text = 'vinieron a clase.';
            app.AadirestudianteButton = uibutton(app.UIFigure, 'push');
            app.AadirestudianteButton.ButtonPushedFcn = createCallbackFcn(app, @AadirestudianteButtonPushed, true);
            app.AadirestudianteButton.Visible = 'off';
            app.AadirestudianteButton.Text = 'Añadir estudiante';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = {'  '; ''; ''; ''};
            app.HolaLabel = uilabel(app.UIFigure);
            app.HolaLabel.Visible = 'off';
            app.HolaLabel.Text = 'Hola ';
            app.noeresdeestaclaseperovienvenidxLabel = uilabel(app.UIFigure);
            app.noeresdeestaclaseperovienvenidxLabel.Visible = 'off';
            app.noeresdeestaclaseperovienvenidxLabel.Text = 'no eres de esta clase pero, vienvenidx!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PresentePresidente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end