classdef anadirEstudiantePopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ListoButton              matlab.ui.control.Button
        EscribetunombreLabel     matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        UtilizaresteaudioButton  matlab.ui.control.Button
        GrabarotravezButton      matlab.ui.control.Button
        OirtuaudioButton         matlab.ui.control.Button
        GrabandoLabel            matlab.ui.control.Label
        GrabarButton             matlab.ui.control.Button
        GrabatuaudiodiciendoHolaatodosminombrees____Label  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
        vocieAudio
        recorder
        fs
        voicePlot
    end
    
    methods (Access = private)
        
        function plotAudio(app)
            t = (0:length(app.vocieAudio)-1)/app.fs;
            app.voicePlot = plot(app.UIAxes, t, app.vocieAudio);
        end

        function saveAudioFile(app, nameInp)
            %FIND THE HIGHEST NUMBER NAME  
    
            pat = digitsPattern;

            DBfiles = dir("baseDeDatosReChidori\");

            maxiInd = 0;
            
            for i = 1 : length(DBfiles)
                if (contains(DBfiles(i).name, "mp3") || contains(DBfiles(i).name, "wav"))
                    file ="baseDeDatosReChidori\" + DBfiles(i).name;

                    nombre = DBfiles(i).name;

                    nombre = erase(nombre,".mp3")
                    onlyNumbers = extract(nombre, pat)
                    onlyNumbers = cell2mat(onlyNumbers)
                    n = str2double(onlyNumbers)
                   

                    if (n > maxiInd)
                        maxiInd = n;
                    end 
                end
            end

            fn = "baseDeDatosReChidori\" + int2str(n+1) + "Voz_" + nameInp + ".wav"
            audiowrite(fn, app.vocieAudio , app.fs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.fs = 48000;
            app.recorder = audiorecorder(app.fs, 24, 1);
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.GrabarButton.Visible = "off";
            app.GrabandoLabel.Visible = "on";
            recordblocking(app.recorder, 6);
            vocieAudioNN = getaudiodata(app.recorder, 'double');
            
            %SE NORMALIZA A 0.5 (para evitar cliping)
            app.vocieAudio = (vocieAudioNN ./ max(abs(vocieAudioNN), [], 1));

            app.GrabandoLabel.Visible = "off";

            app.plotAudio()
            app.UIAxes.Visible = "on";
            app.OirtuaudioButton.Visible = "on";
            app.GrabarotravezButton.Visible = "on";
            app.UtilizaresteaudioButton.Visible = "on";
        end

        % Button pushed function: OirtuaudioButton
        function OirtuaudioButtonPushed(app, event)
            set(app.OirtuaudioButton, "Enable", "off")
            sound(app.vocieAudio, app.fs)
            pause(1.5)
            set(app.OirtuaudioButton, "Enable", "on")
        end

        % Button pushed function: UtilizaresteaudioButton
        function UtilizaresteaudioButtonPushed(app, event)
            app.OirtuaudioButton.Visible = "off";
            app.GrabarotravezButton.Visible = "off";
            app.UtilizaresteaudioButton.Visible = "off";
            app.UIAxes.Visible = "off";
            app.voicePlot.Visible = "off";

            app.EditField.Visible = "on";
            app.EscribetunombreLabel.Visible = "on";
            app.ListoButton.Visible = "on";

        end

        % Button pushed function: GrabarotravezButton
        function GrabarotravezButtonPushed(app, event)
            app.OirtuaudioButton.Visible = "off";
            app.GrabarotravezButton.Visible = "off";
            app.UtilizaresteaudioButton.Visible = "off";

            app.UIAxes.Visible = "off";
            app.voicePlot.Visible = "off";

            app.GrabandoLabel.Visible = "on";
            recordblocking(app.recorder, 6);
            app.vocieAudio = getaudiodata(app.recorder, 'double');
            app.GrabandoLabel.Visible = "off";

            app.plotAudio()
            app.voicePlot.Visible = "on";

            app.UIAxes.Visible = "on";
            app.OirtuaudioButton.Visible = "on";
            app.GrabarotravezButton.Visible = "on";
            app.UtilizaresteaudioButton.Visible = "on";
        end

        % Button pushed function: ListoButton
        function ListoButtonPushed(app, event)
            name = app.EditField.Value;
            app.saveAudioFile(name);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tu grabacion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.GrabatuaudiodiciendoHolaatodosminombrees____Label = uilabel(app.UIFigure);
            app.GrabatuaudiodiciendoHolaatodosminombrees____Label.Text = 'Graba tu audio diciendo "Hola a todos, mi nombre es ____"';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.GrabandoLabel = uilabel(app.UIFigure);
            app.GrabandoLabel.Visible = 'off';
            app.GrabandoLabel.Text = 'Grabando...';
            app.OirtuaudioButton = uibutton(app.UIFigure, 'push');
            app.OirtuaudioButton.ButtonPushedFcn = createCallbackFcn(app, @OirtuaudioButtonPushed, true);
            app.OirtuaudioButton.Visible = 'off';
            app.OirtuaudioButton.Text = 'Oir tu audio';
            app.GrabarotravezButton = uibutton(app.UIFigure, 'push');
            app.GrabarotravezButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarotravezButtonPushed, true);
            app.GrabarotravezButton.Visible = 'off';
            app.GrabarotravezButton.Text = 'Grabar otra vez';
            app.UtilizaresteaudioButton = uibutton(app.UIFigure, 'push');
            app.UtilizaresteaudioButton.ButtonPushedFcn = createCallbackFcn(app, @UtilizaresteaudioButtonPushed, true);
            app.UtilizaresteaudioButton.Visible = 'off';
            app.UtilizaresteaudioButton.Text = 'Utilizar este audio!';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            app.EscribetunombreLabel = uilabel(app.UIFigure);
            app.EscribetunombreLabel.Visible = 'off';
            app.EscribetunombreLabel.Text = 'Escribe tu nombre';
            app.ListoButton = uibutton(app.UIFigure, 'push');
            app.ListoButton.ButtonPushedFcn = createCallbackFcn(app, @ListoButtonPushed, true);
            app.ListoButton.Visible = 'off';
            app.ListoButton.Text = 'Listo!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anadirEstudiantePopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end