classdef llamadindividualPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UtilizaresteaudioButton  matlab.ui.control.Button
        GrabarotravezButton      matlab.ui.control.Button
        OirtuaudioButton         matlab.ui.control.Button
        GrabandoLabel            matlab.ui.control.Label
        GrabatuaudiodiciendoPresenteLabel  matlab.ui.control.Label
        Recuerdaquesolotienes3segundosLabel  matlab.ui.control.Label
        GrabarButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
        vocieAudio
        recorder
        fs
        voicePlot
    end 
    methods (Access = private)
        
        function plotAudio(app)
            t = (0:length(app.vocieAudio)-1)/app.fs;
            app.voicePlot = plot(app.UIAxes, t, app.vocieAudio);
        end    

        function filteredSignal = filterAudio(app, signal)
            lowCutoff = 300;  
            highCutoff = 3400;
            Fs = app.fs;
            [b, a] = butter(5, [lowCutoff, highCutoff] / (Fs / 2), 'bandpass');
            filteredSignal = filtfilt(b, a, signal);
        end

        function reconstructedSignal = doSpectogramAnalisis(app, signal)
            segmentlen = 100;
            noverlap = 90;
            NFFT = 128;
            Fs = app.fs;
            
            %Primero vamos a sacar el espectograma de la señal     
            [S, F, T, P] = spectrogram(signal,segmentlen,noverlap,NFFT,Fs,'yaxis');

            %Luego vamos a definir unos valores de potencia y un threshhold
            %para solo dejar pasar valores a partir de este threshhold
            P_dB = 10 * log10(P);
            powerThreshold = -50; 
            mask = P_dB > powerThreshold;
            
            reconstructedSignal = zeros(size(signal));
            
            %En este for, se aplicara la mascara a la señal y se
            %reconstruira al dominio del tiempo
            for k = 1:length(T)
                %Mascara
                maskedSpectrum = S(:, k) .* mask(:, k);
                
                %FFT inversa
                segment = ifft(maskedSpectrum, NFFT);
                
                %Determinar indices basados en las variables del windowing
                startIdx = (k-1) * (segmentlen - noverlap) + 1;
                endIdx = startIdx + segmentlen - 1;
                
                %Asegurarse que los indices tengan sentido
                if endIdx <= length(reconstructedSignal)
                    reconstructedSignal(startIdx:endIdx) = reconstructedSignal(startIdx:endIdx) + real(segment(1:segmentlen));
                end
            end
            
            %Normalizar la señal reconstruida
            reconstructedSignal = reconstructedSignal / max(abs(reconstructedSignal));
        end

        function [startId, endId] = cropNesesarySignal(app, reconstructedSignal)
            startId = 1;
            endId = 1;
            
            %Definir valor inicial
            for i = 1: length(reconstructedSignal)
                if(reconstructedSignal(i) ~= 0)
                    startId = i;
                    break
                end
            end 
                
            %Definir final
            for i = startId: length(reconstructedSignal)
                if(reconstructedSignal(i:i+100) == 0)
                        endId = i;
                        break
                end
            end 
            return
        end 

        function maxCepstrum = getSepstrum(app, signal)
            
            %Primero vamos a filtrar el adudio
            filteredSignal = app.filterAudio(signal);

            %Luego vamos a hacer el analisis de espectograma para lograr
            %quitar ruido y datos innecesarios del audio
            reconstructedSignal = app.doSpectogramAnalisis(filteredSignal);

            %Luego se tomara una seccion del audio donde el usuario este
            %hablando
            [startInd, endInd] = cropNesesarySignal(app, reconstructedSignal);

            t = (0:length(signal)-1)/app.fs;

            tSt = t(startInd);
            tEn = t(endInd);
            
            dt = 1/app.fs;
            I0 = round(tSt/dt);
            Iend = round(tEn/dt);
            cropReconstructedSignal = reconstructedSignal(I0:Iend);

            %Se saca el ceptrum de la señal filtrada, reconstruida y
            %cortada
            c = cceps(cropReconstructedSignal);

            %Se saca el maximo del cepstrum para tener un aproximado
            %(cercano) de la frecuancia fundamental
            maxCepstrum = max(c);

        end   

        function personaDetectada = doProcessing(app)
            DBfiles = dir("baseDeDatosReChidori\");

            minDif = inf;
            minInd = -1;

            %PROCESAMIENTO Y CALCULO DE SEPSTRUM DEL AUDIO INTRODUCIDO POR
            %EL USUARIO
            maxCepstrumUS = getSepstrum(app, app.vocieAudio);

            
            for i = 1 : length(DBfiles)
                if (contains(DBfiles(i).name, "mp3") || contains(DBfiles(i).name, "wav"))
                    file = "baseDeDatosReChidori\" + DBfiles(i).name;
                    [dataT,~] = audioread(file);
                    dataT = (dataT ./ max(abs(dataT), [], 1));

                    maxCepstrumDB = getSepstrum(app, dataT);

                    subs = abs(maxCepstrumDB - maxCepstrumUS);

                    %Encontrar cual de todas las notas de voz tiene menor
                    %diferencia de sepstrum  
                    if (subs < minDif)
                        disp("olaa")
                        minDif = subs;
                        minInd = i;
                    end 
                end
            end

            nombre = DBfiles(minInd).name
            nombre = erase(nombre,".mp3");
            onlyNumbers = extract(nombre, digitsPattern);
            onlyNumbers = cell2mat(onlyNumbers);
            n = str2double(onlyNumbers);
            personaDetectada = 4;

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.fs = 48000;
            app.recorder = audiorecorder(app.fs, 24, 1);
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.GrabarButton.Visible = "off";
            app.GrabandoLabel.Visible = "on";
            recordblocking(app.recorder, 3);
            vocieAudioNN = getaudiodata(app.recorder, 'double');
            
            %SE NORMALIZA A 0.5 (para evitar cliping)
            app.vocieAudio = (vocieAudioNN ./ max(abs(vocieAudioNN), [], 1));

            app.GrabandoLabel.Visible = "off";

            app.plotAudio()
            app.UIAxes.Visible = "on";
            app.OirtuaudioButton.Visible = "on";
            app.GrabarotravezButton.Visible = "on";
            app.UtilizaresteaudioButton.Visible = "on";
        end

        % Button pushed function: OirtuaudioButton
        function OirtuaudioButtonPushed(app, event)
            set(app.OirtuaudioButton, "Enable", "off")
            sound(app.vocieAudio, app.fs)
            pause(1.5)
            set(app.OirtuaudioButton, "Enable", "on")
        end

        % Button pushed function: GrabarotravezButton
        function GrabarotravezButtonPushed(app, event)
            app.OirtuaudioButton.Visible = "off";
            app.GrabarotravezButton.Visible = "off";
            app.UtilizaresteaudioButton.Visible = "off";

            app.UIAxes.Visible = "off";
            app.voicePlot.Visible = "off";

            app.GrabandoLabel.Visible = "on";
            recordblocking(app.recorder, 3);
            app.vocieAudio = getaudiodata(app.recorder, 'double');
            app.GrabandoLabel.Visible = "off";

            app.plotAudio()
            app.voicePlot.Visible = "on";

            app.UIAxes.Visible = "on";
            app.OirtuaudioButton.Visible = "on";
            app.GrabarotravezButton.Visible = "on";
            app.UtilizaresteaudioButton.Visible = "on";
        end

        % Button pushed function: UtilizaresteaudioButton
        function UtilizaresteaudioButtonPushed(app, event)
            personaDetectada = app.doProcessing();
            app.CallingApp.GetPersonInClass(personaDetectada)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tu grabacion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.Recuerdaquesolotienes3segundosLabel = uilabel(app.UIFigure);
            app.Recuerdaquesolotienes3segundosLabel.Text = 'Recuerda que solo tienes 3 segundos!';
            app.GrabatuaudiodiciendoPresenteLabel = uilabel(app.UIFigure);
            app.GrabatuaudiodiciendoPresenteLabel.Text = 'Graba tu audio diciendo "Presente"';
            app.GrabandoLabel = uilabel(app.UIFigure);
            app.GrabandoLabel.Visible = 'off';
            app.GrabandoLabel.Text = 'Grabando...';
            app.OirtuaudioButton = uibutton(app.UIFigure, 'push');
            app.OirtuaudioButton.ButtonPushedFcn = createCallbackFcn(app, @OirtuaudioButtonPushed, true);
            app.OirtuaudioButton.Visible = 'off';
            app.OirtuaudioButton.Text = 'Oir tu audio';
            app.GrabarotravezButton = uibutton(app.UIFigure, 'push');
            app.GrabarotravezButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarotravezButtonPushed, true);
            app.GrabarotravezButton.Visible = 'off';
            app.GrabarotravezButton.Text = 'Grabar otra vez';
            app.UtilizaresteaudioButton = uibutton(app.UIFigure, 'push');
            app.UtilizaresteaudioButton.ButtonPushedFcn = createCallbackFcn(app, @UtilizaresteaudioButtonPushed, true);
            app.UtilizaresteaudioButton.Visible = 'off';
            app.UtilizaresteaudioButton.Text = 'Utilizar este audio!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = llamadindividualPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end